classdef patientData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PatientDataPanel               matlab.ui.container.Panel
        RightEarLabel                  matlab.ui.control.Label
        HzEditField_16                 matlab.ui.control.NumericEditField
        HzLabel_12                     matlab.ui.control.Label
        HzEditField_15                 matlab.ui.control.NumericEditField
        HzLabel_11                     matlab.ui.control.Label
        HzEditField_14                 matlab.ui.control.NumericEditField
        HzLabel_10                     matlab.ui.control.Label
        HzEditField_13                 matlab.ui.control.NumericEditField
        HzEditFieldLabel_4             matlab.ui.control.Label
        HzEditField_12                 matlab.ui.control.NumericEditField
        HzLabel_9                      matlab.ui.control.Label
        HzEditField_11                 matlab.ui.control.NumericEditField
        HzLabel_8                      matlab.ui.control.Label
        HzEditField_10                 matlab.ui.control.NumericEditField
        HzLabel_7                      matlab.ui.control.Label
        HzEditField_9                  matlab.ui.control.NumericEditField
        HzEditFieldLabel_3             matlab.ui.control.Label
        HzEditField_8                  matlab.ui.control.NumericEditField
        HzLabel_6                      matlab.ui.control.Label
        HzEditField_7                  matlab.ui.control.NumericEditField
        HzLabel_5                      matlab.ui.control.Label
        HzEditField_6                  matlab.ui.control.NumericEditField
        HzLabel_4                      matlab.ui.control.Label
        HzEditField_5                  matlab.ui.control.NumericEditField
        HzEditFieldLabel_2             matlab.ui.control.Label
        HzEditField_4                  matlab.ui.control.NumericEditField
        HzLabel_3                      matlab.ui.control.Label
        HzEditField_3                  matlab.ui.control.NumericEditField
        HzLabel_2                      matlab.ui.control.Label
        HzEditField_2                  matlab.ui.control.NumericEditField
        HzLabel                        matlab.ui.control.Label
        HearingThresholdLevelsLabel    matlab.ui.control.Label
        LeftEarLabel                   matlab.ui.control.Label
        HzEditField                    matlab.ui.control.NumericEditField
        HzEditFieldLabel               matlab.ui.control.Label
        YearssincerightearhearingdeviceinsertionEditField  matlab.ui.control.NumericEditField
        YearssincerightearhearingdeviceinsertionEditField_2Label  matlab.ui.control.Label
        YearssinceleftearhearingdeviceinsertionEditField  matlab.ui.control.NumericEditField
        YearssinceleftearhearingdeviceinsertionEditFieldLabel  matlab.ui.control.Label
        RightEarHearingDeviceDropDown  matlab.ui.control.DropDown
        RightEarHearingDeviceDropDownLabel  matlab.ui.control.Label
        LeftEarHearingDeviceDropDown   matlab.ui.control.DropDown
        LeftEarHearingDeviceDropDownLabel  matlab.ui.control.Label
        NextVolumeCallibrationButton   matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        SexDropDown                    matlab.ui.control.DropDown
        SexDropDownLabel               matlab.ui.control.Label
        DateofBirthDatePicker          matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel     matlab.ui.control.Label
        PatientIDEditField             matlab.ui.control.NumericEditField
        PatientIDEditFieldLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LeftEarHearingDeviceDropDown
        function LeftEarSelection(app, event)
            value = app.LeftEarHearingDeviceDropDown.Value;
            if strcmp(value, "None")
                app.YearssinceleftearhearingdeviceinsertionEditField.Enable = false;
            else
                app.YearssinceleftearhearingdeviceinsertionEditField.Enable = true;
            end
        end

        % Value changed function: RightEarHearingDeviceDropDown
        function RightEarSelection(app, event)
            value = app.RightEarHearingDeviceDropDown.Value;
            if strcmp(value, "None")
                app.YearssincerightearhearingdeviceinsertionEditField.Enable = false;
            else
                app.YearssincerightearhearingdeviceinsertionEditField.Enable = true;
            end
        end

        % Button pushed function: NextVolumeCallibrationButton
        function VolumeCallibration(app, event)
            volumeCallibration
            % save patient data
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientDataPanel = uipanel(app.UIFigure);
            app.PatientDataPanel.Title = 'Patient Data';
            app.Panel = uipanel(app.PatientDataPanel);
            app.PatientIDEditFieldLabel = uilabel(app.Panel);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.Panel, 'numeric');
            app.PatientIDEditField.Limits = [0 Inf];
            app.PatientIDEditField.ValueDisplayFormat = '%.0f';
            app.DateofBirthDatePickerLabel = uilabel(app.Panel);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.Panel);
            app.SexDropDownLabel = uilabel(app.Panel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.Panel);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.NextVolumeCallibrationButton = uibutton(app.PatientDataPanel, 'push');
            app.NextVolumeCallibrationButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeCallibration, true);
            app.NextVolumeCallibrationButton.Text = 'Next: Volume Callibration';
            app.LeftEarHearingDeviceDropDownLabel = uilabel(app.PatientDataPanel);
            app.LeftEarHearingDeviceDropDownLabel.Text = 'Left Ear Hearing Device';
            app.LeftEarHearingDeviceDropDown = uidropdown(app.PatientDataPanel);
            app.LeftEarHearingDeviceDropDown.Items = {'None', 'Hearing Aid', 'Cochlear Implant'};
            app.LeftEarHearingDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftEarSelection, true);
            app.LeftEarHearingDeviceDropDown.Value = 'None';
            app.RightEarHearingDeviceDropDownLabel = uilabel(app.PatientDataPanel);
            app.RightEarHearingDeviceDropDownLabel.Text = 'Right Ear Hearing Device';
            app.RightEarHearingDeviceDropDown = uidropdown(app.PatientDataPanel);
            app.RightEarHearingDeviceDropDown.Items = {'None', 'Hearing Aid', 'Cochlear Implant'};
            app.RightEarHearingDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @RightEarSelection, true);
            app.RightEarHearingDeviceDropDown.Value = 'None';
            app.YearssinceleftearhearingdeviceinsertionEditFieldLabel = uilabel(app.PatientDataPanel);
            app.YearssinceleftearhearingdeviceinsertionEditFieldLabel.Text = 'Years since left ear hearing device insertion';
            app.YearssinceleftearhearingdeviceinsertionEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.YearssinceleftearhearingdeviceinsertionEditField.Limits = [0 100];
            app.YearssinceleftearhearingdeviceinsertionEditField.ValueDisplayFormat = '%.0f';
            app.YearssinceleftearhearingdeviceinsertionEditField.Enable = 'off';
            app.YearssincerightearhearingdeviceinsertionEditField_2Label = uilabel(app.PatientDataPanel);
            app.YearssincerightearhearingdeviceinsertionEditField_2Label.Text = 'Years since right ear hearing device insertion';
            app.YearssincerightearhearingdeviceinsertionEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.YearssincerightearhearingdeviceinsertionEditField.Limits = [0 100];
            app.YearssincerightearhearingdeviceinsertionEditField.ValueDisplayFormat = '%.0f';
            app.YearssincerightearhearingdeviceinsertionEditField.Enable = 'off';
            app.HzEditFieldLabel = uilabel(app.PatientDataPanel);
            app.HzEditFieldLabel.Text = '125 Hz';
            app.HzEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.LeftEarLabel = uilabel(app.PatientDataPanel);
            app.LeftEarLabel.Text = 'Left Ear';
            app.HearingThresholdLevelsLabel = uilabel(app.PatientDataPanel);
            app.HearingThresholdLevelsLabel.Text = 'Hearing Threshold Levels';
            app.HzLabel = uilabel(app.PatientDataPanel);
            app.HzLabel.Text = '250 Hz';
            app.HzEditField_2 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_2 = uilabel(app.PatientDataPanel);
            app.HzLabel_2.Text = '500 Hz';
            app.HzEditField_3 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_3 = uilabel(app.PatientDataPanel);
            app.HzLabel_3.Text = '1000 Hz';
            app.HzEditField_4 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzEditFieldLabel_2 = uilabel(app.PatientDataPanel);
            app.HzEditFieldLabel_2.Text = '2000 Hz';
            app.HzEditField_5 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_4 = uilabel(app.PatientDataPanel);
            app.HzLabel_4.Text = '3000 Hz';
            app.HzEditField_6 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_5 = uilabel(app.PatientDataPanel);
            app.HzLabel_5.Text = ' 4000 Hz';
            app.HzEditField_7 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_6 = uilabel(app.PatientDataPanel);
            app.HzLabel_6.Text = '8000 Hz';
            app.HzEditField_8 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzEditFieldLabel_3 = uilabel(app.PatientDataPanel);
            app.HzEditFieldLabel_3.Text = '125 Hz';
            app.HzEditField_9 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_7 = uilabel(app.PatientDataPanel);
            app.HzLabel_7.Text = '250 Hz';
            app.HzEditField_10 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_8 = uilabel(app.PatientDataPanel);
            app.HzLabel_8.Text = '500 Hz';
            app.HzEditField_11 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_9 = uilabel(app.PatientDataPanel);
            app.HzLabel_9.Text = '1000 Hz';
            app.HzEditField_12 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzEditFieldLabel_4 = uilabel(app.PatientDataPanel);
            app.HzEditFieldLabel_4.Text = '2000 Hz';
            app.HzEditField_13 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_10 = uilabel(app.PatientDataPanel);
            app.HzLabel_10.Text = '3000 Hz';
            app.HzEditField_14 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_11 = uilabel(app.PatientDataPanel);
            app.HzLabel_11.Text = ' 4000 Hz';
            app.HzEditField_15 = uieditfield(app.PatientDataPanel, 'numeric');
            app.HzLabel_12 = uilabel(app.PatientDataPanel);
            app.HzLabel_12.Text = '8000 Hz';
            app.HzEditField_16 = uieditfield(app.PatientDataPanel, 'numeric');
            app.RightEarLabel = uilabel(app.PatientDataPanel);
            app.RightEarLabel.Text = 'Right Ear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patientData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end