classdef bimodalIntegrationTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PhonemeRecognitionPanel  matlab.ui.container.Panel
        Trial1of100Label         matlab.ui.control.Label
        Sound1of15Label          matlab.ui.control.Label
        NextTrialButton          matlab.ui.control.Button
        hooedLabel               matlab.ui.control.Label
        DifferentLabel           matlab.ui.control.Label
        SameLabel                matlab.ui.control.Label
        HearSoundsButton         matlab.ui.control.Button
        JButton                  matlab.ui.control.StateButton
        FButton                  matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FButton
        function ToggleF(app, event)
            value = app.FButton.Value;
            if value
                app.JButton.Value = ~value;
                app.NextTrialButton.Enable = true;
            else
                app.NextTrialButton.Enable = false;
            end
        end

        % Value changed function: JButton
        function ToggleJ(app, event)
            value = app.JButton.Value;
            if value
                app.FButton.Value = ~value;
                app.NextTrialButton.Enable = true;
            else
                app.NextTrialButton.Enable = false;
            end
        end

        % Button pushed function: NextTrialButton
        function EndTest(app, event)
            testCompletedCard
            pause(2);
            delete(app);
        end

        % Callback function
        function PlayPrimarySound(app, event)
            % play sound from audio file
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PhonemeRecognitionPanel = uipanel(app.UIFigure);
            app.PhonemeRecognitionPanel.Title = 'Phoneme Recognition';
            app.FButton = uibutton(app.PhonemeRecognitionPanel, 'state');
            app.FButton.ValueChangedFcn = createCallbackFcn(app, @ToggleF, true);
            app.FButton.Text = 'F';
            app.JButton = uibutton(app.PhonemeRecognitionPanel, 'state');
            app.JButton.ValueChangedFcn = createCallbackFcn(app, @ToggleJ, true);
            app.JButton.Text = 'J';
            app.HearSoundsButton = uibutton(app.PhonemeRecognitionPanel, 'push');
            app.HearSoundsButton.Text = 'Hear Sounds';
            app.SameLabel = uilabel(app.PhonemeRecognitionPanel);
            app.SameLabel.Text = 'Same';
            app.DifferentLabel = uilabel(app.PhonemeRecognitionPanel);
            app.DifferentLabel.Text = 'Different';
            app.hooedLabel = uilabel(app.PhonemeRecognitionPanel);
            app.hooedLabel.Text = '/hooed/';
            app.NextTrialButton = uibutton(app.PhonemeRecognitionPanel, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @EndTest, true);
            app.NextTrialButton.Enable = 'off';
            app.NextTrialButton.Text = 'Next Trial';
            app.Sound1of15Label = uilabel(app.PhonemeRecognitionPanel);
            app.Sound1of15Label.Text = 'Sound 1 of 15';
            app.Trial1of100Label = uilabel(app.PhonemeRecognitionPanel);
            app.Trial1of100Label.Text = 'Trial 1 of 100';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bimodalIntegrationTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end