classdef patientData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PatientDataPanel               matlab.ui.container.Panel
        YearssincerightearhearingdeviceinsertionEditField  matlab.ui.control.NumericEditField
        YearssincerightearhearingdeviceinsertionEditField_2Label  matlab.ui.control.Label
        NextVolumeCallibrationButton   matlab.ui.control.Button
        YearssinceleftearhearingdeviceinsertionEditField  matlab.ui.control.NumericEditField
        YearssinceleftearhearingdeviceinsertionEditFieldLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        EditField                      matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        SexDropDown                    matlab.ui.control.DropDown
        SexDropDownLabel               matlab.ui.control.Label
        DateofBirthDatePicker          matlab.ui.control.DatePicker
        DateofBirthDatePickerLabel     matlab.ui.control.Label
        PatientIDEditField             matlab.ui.control.NumericEditField
        PatientIDEditFieldLabel        matlab.ui.control.Label
        RightEarHearingDeviceDropDown  matlab.ui.control.DropDown
        RightEarHearingDeviceDropDownLabel  matlab.ui.control.Label
        LeftEarHearingDeviceDropDown   matlab.ui.control.DropDown
        LeftEarHearingDeviceDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LeftEarHearingDeviceDropDown
        function LeftEarSelection(app, event)
            value = app.LeftEarHearingDeviceDropDown.Value;
            if strcmp(value, "None")
                app.YearssinceleftearhearingdeviceinsertionEditField.Enable = false;
            else
                app.YearssinceleftearhearingdeviceinsertionEditField.Enable = true;
            end
        end

        % Value changed function: RightEarHearingDeviceDropDown
        function RightEarSelection(app, event)
            value = app.RightEarHearingDeviceDropDown.Value;
            if strcmp(value, "None")
                app.YearssincerightearhearingdeviceinsertionEditField.Enable = false;
            else
                app.YearssincerightearhearingdeviceinsertionEditField.Enable = true;
            end
        end

        % Button pushed function: NextVolumeCallibrationButton
        function VolumeCallibration(app, event)
            volumeCallibration
            % save patient data
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientDataPanel = uipanel(app.UIFigure);
            app.PatientDataPanel.Title = 'Patient Data';
            app.LeftEarHearingDeviceDropDownLabel = uilabel(app.PatientDataPanel);
            app.LeftEarHearingDeviceDropDownLabel.Text = 'Left Ear Hearing Device';
            app.LeftEarHearingDeviceDropDown = uidropdown(app.PatientDataPanel);
            app.LeftEarHearingDeviceDropDown.Items = {'None', 'Hearing Aid', 'Cochlear Implant'};
            app.LeftEarHearingDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @LeftEarSelection, true);
            app.LeftEarHearingDeviceDropDown.Value = 'None';
            app.RightEarHearingDeviceDropDownLabel = uilabel(app.PatientDataPanel);
            app.RightEarHearingDeviceDropDownLabel.Text = 'Right Ear Hearing Device';
            app.RightEarHearingDeviceDropDown = uidropdown(app.PatientDataPanel);
            app.RightEarHearingDeviceDropDown.Items = {'None', 'Hearing Aid', 'Cochlear Implant'};
            app.RightEarHearingDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @RightEarSelection, true);
            app.RightEarHearingDeviceDropDown.Value = 'None';
            app.Panel = uipanel(app.PatientDataPanel);
            app.PatientIDEditFieldLabel = uilabel(app.Panel);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.Panel, 'numeric');
            app.PatientIDEditField.Limits = [0 Inf];
            app.PatientIDEditField.ValueDisplayFormat = '%.0f';
            app.DateofBirthDatePickerLabel = uilabel(app.Panel);
            app.DateofBirthDatePickerLabel.Text = 'Date of Birth';
            app.DateofBirthDatePicker = uidatepicker(app.Panel);
            app.SexDropDownLabel = uilabel(app.Panel);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.Panel);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Panel, 'text');
            app.YearssinceleftearhearingdeviceinsertionEditFieldLabel = uilabel(app.PatientDataPanel);
            app.YearssinceleftearhearingdeviceinsertionEditFieldLabel.Text = 'Years since left ear hearing device insertion';
            app.YearssinceleftearhearingdeviceinsertionEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.YearssinceleftearhearingdeviceinsertionEditField.Limits = [0 100];
            app.YearssinceleftearhearingdeviceinsertionEditField.ValueDisplayFormat = '%.0f';
            app.YearssinceleftearhearingdeviceinsertionEditField.Enable = 'off';
            app.NextVolumeCallibrationButton = uibutton(app.PatientDataPanel, 'push');
            app.NextVolumeCallibrationButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeCallibration, true);
            app.NextVolumeCallibrationButton.Text = 'Next: Volume Callibration';
            app.YearssincerightearhearingdeviceinsertionEditField_2Label = uilabel(app.PatientDataPanel);
            app.YearssincerightearhearingdeviceinsertionEditField_2Label.Text = 'Years since right ear hearing device insertion';
            app.YearssincerightearhearingdeviceinsertionEditField = uieditfield(app.PatientDataPanel, 'numeric');
            app.YearssincerightearhearingdeviceinsertionEditField.Limits = [0 100];
            app.YearssincerightearhearingdeviceinsertionEditField.ValueDisplayFormat = '%.0f';
            app.YearssincerightearhearingdeviceinsertionEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = patientData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end