classdef bimodalIntegrationTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PhonemeRecognitionSound1of15Trial1of100Panel  matlab.ui.container.Panel
        HearSoundAButton  matlab.ui.control.Button
        NextTrialButton   matlab.ui.control.Button
        maLabel           matlab.ui.control.Label
        DifferentLabel    matlab.ui.control.Label
        SameLabel         matlab.ui.control.Label
        HearSoundBButton  matlab.ui.control.Button
        JButton           matlab.ui.control.StateButton
        FButton           matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FButton
        function ToggleF(app, event)
            value = app.FButton.Value;
            if value
                app.JButton.Value = ~value;
                app.NextTrialButton.Enable = true;
            else
                app.NextTrialButton.Enable = false;
            end
        end

        % Value changed function: JButton
        function ToggleJ(app, event)
            value = app.JButton.Value;
            if value
                app.FButton.Value = ~value;
                app.NextTrialButton.Enable = true;
            else
                app.NextTrialButton.Enable = false;
            end
        end

        % Button pushed function: NextTrialButton
        function EndTest(app, event)
            testCompletedCard
            pause(2);
            delete(app);
        end

        % Button pushed function: HearSoundAButton
        function PlayPrimarySound(app, event)
            % play sound from audio file
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PhonemeRecognitionSound1of15Trial1of100Panel = uipanel(app.UIFigure);
            app.PhonemeRecognitionSound1of15Trial1of100Panel.Title = 'Phoneme Recognition                                       Sound 1 of 15                                                    Trial 1 of 100';
            app.FButton = uibutton(app.PhonemeRecognitionSound1of15Trial1of100Panel, 'state');
            app.FButton.ValueChangedFcn = createCallbackFcn(app, @ToggleF, true);
            app.FButton.Text = 'F';
            app.JButton = uibutton(app.PhonemeRecognitionSound1of15Trial1of100Panel, 'state');
            app.JButton.ValueChangedFcn = createCallbackFcn(app, @ToggleJ, true);
            app.JButton.Text = 'J';
            app.HearSoundBButton = uibutton(app.PhonemeRecognitionSound1of15Trial1of100Panel, 'push');
            app.HearSoundBButton.Text = 'Hear Sound B';
            app.SameLabel = uilabel(app.PhonemeRecognitionSound1of15Trial1of100Panel);
            app.SameLabel.Text = 'Same';
            app.DifferentLabel = uilabel(app.PhonemeRecognitionSound1of15Trial1of100Panel);
            app.DifferentLabel.Text = 'Different';
            app.maLabel = uilabel(app.PhonemeRecognitionSound1of15Trial1of100Panel);
            app.maLabel.Text = '/ma/';
            app.NextTrialButton = uibutton(app.PhonemeRecognitionSound1of15Trial1of100Panel, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @EndTest, true);
            app.NextTrialButton.Enable = 'off';
            app.NextTrialButton.Text = 'Next Trial';
            app.HearSoundAButton = uibutton(app.PhonemeRecognitionSound1of15Trial1of100Panel, 'push');
            app.HearSoundAButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPrimarySound, true);
            app.HearSoundAButton.Text = 'Hear Sound A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bimodalIntegrationTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end